classdef AWGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        configTab           matlab.ui.container.Tab
        PingTestPanel       matlab.ui.container.Panel
        PingServerButton    matlab.ui.control.Button
        PingTestLamp        matlab.ui.control.Lamp
        PingTestErrorLabel  matlab.ui.control.Label
        errorLogTab         matlab.ui.container.Tab
        uploadtraceTab      matlab.ui.container.Tab
        outputcontrolsTab   matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PingServerButtonPushed(app)
        end

        % Button pushed function: PingServerButton
        function PingServerButtonPushed(app, event)
            [status, commandOut] = system('python C:\Users\anton\Documents\FYP repository\Remotomation\client\pingTest.py');
            if status==2
                uialert(app.UIFigure,"python files not found add them using...","startup error")
                app.PingTestLamp.Color=[1.0,0.0,0.0];
                app.PingTestErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            if status==1
                app.PingTestLamp.Color=[1.0,0.0,0.0];
                app.PingTestErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            if status==0
                app.PingTestLamp.Color=[0.0,1.0,0.0];
                app.PingTestErrorLabel.FontColor=[0.0,1.0,0.0];
            end
            app.PingTestErrorLabel.Text=commandOut;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.configTab = uitab(app.TabGroup);
            app.configTab.Title = 'config';
            app.PingTestPanel = uipanel(app.configTab);
            app.PingServerButton = uibutton(app.PingTestPanel, 'push');
            app.PingServerButton.ButtonPushedFcn = createCallbackFcn(app, @PingServerButtonPushed, true);
            app.PingServerButton.Text = 'Ping Server';
            app.PingTestLamp = uilamp(app.PingTestPanel);
            app.PingTestErrorLabel = uilabel(app.PingTestPanel);
            app.PingTestErrorLabel.Text = '';
            app.errorLogTab = uitab(app.TabGroup);
            app.errorLogTab.Title = 'errorLog';
            app.uploadtraceTab = uitab(app.TabGroup);
            app.uploadtraceTab.Title = 'upload trace';
            app.outputcontrolsTab = uitab(app.TabGroup);
            app.outputcontrolsTab.Title = 'output controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end